classdef CONVET < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        BienvenidoaCONVOTunsistemadeconversindevozatextoLabel  matlab.ui.control.Label
        Image2                     matlab.ui.control.Image
        Image                      matlab.ui.control.Image
        ELIMINARButton             matlab.ui.control.Button
        GRABARButton               matlab.ui.control.Button
        TuvozhadichoTextArea       matlab.ui.control.TextArea
        TuvozhadichoTextAreaLabel  matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        audiorecorder
        recordedData
        svm_model = load('model.mat');
        audio_path = 'audios_test';
        coeff = table2array(readtable("coeff.csv",Delimiter=','));
        extra_table = readtable("extra.csv",Delimiter=',');
        mu = table2array(readtable('mu.csv',Delimiter=','));
        sigma = table2array(readtable('sigma.csv',Delimiter=','));
        fs = 48000;
    end
    
    methods (Access = private)
        
        function audio_vector = transform_audio(app,name)
            possible_fs = [768000, 384000, 192000, 96000, 48000, 24000, 12000, 705600, 352800, 176400, 88200, 44100, 22050, 11025, 32000, 16000, 8000];
            [audio, fs_x] = audioread(strcat("audios/",name));
                if(size(audio,2) > 1)
                    audio = mean(audio,2);
                end
                [~, index] = min(abs(possible_fs - fs_x));
                audio = audioresample(audio,InputRate=possible_fs(index),OutputRate=app.fs);
                mfccs = mfcc(audio,app.fs);
                mfccs = reshape(mfccs,[],1);
                centroid = spectralCentroid(audio, app.fs);
                centroid(isnan(centroid)) = 0;
                spread = spectralSpread(audio,app.fs);
                spread(isnan(spread)) = 0;
                kurtosis = spectralKurtosis(audio,app.fs);
                kurtosis(isnan(kurtosis)) = 0;
                % Concatenar las características en un solo vector
                mfcc_new = zeros([1,app.extra_table.max_mfccs]);
                mfcc_new(1:length(mfccs)) = mfccs;
                centroid_new = zeros([1,app.extra_table.max_centroid]);
                centroid_new(1:length(centroid)) = centroid;
                spread_new = zeros([1,app.extra_table.max_spread]);
                spread_new(1:length(spread)) = spread;
                kurtosis_new = zeros([1,app.extra_table.max_kurtosis]);
                kurtosis_new(1:length(kurtosis)) = kurtosis;
                features = horzcat(mfcc_new,centroid_new,spread_new,kurtosis_new);
                featuresNormalized = (features - app.mu) ./ app.sigma;
                audio_vector = featuresNormalized * app.coeff;
            end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GRABARButton
        function GRABARButtonPushed(app, event)
            % Verificar si está grabando actualmente
            if ~isempty(app.audiorecorder) && isrecording(app.audiorecorder)
                stop(app.audiorecorder); % Detener la grabación
                app.recordedData = getaudiodata(app.audiorecorder); % Obtener los datos grabados
                plot(app.UIAxes, app.recordedData); % Plotear los datos grabados
                title(app.UIAxes, 'Audio Grabado');
                xlabel(app.UIAxes, 'Muestras');
                ylabel(app.UIAxes, 'Amplitud');
                app.GRABARButton.Text = 'GRABAR'; % Cambiar el texto del botón
            else
                app.audiorecorder = app.audiorecorder; % Crear un objeto de grabación de audio
                record(app.audiorecorder); % Iniciar la grabación
                app.GRABARButton.Text = 'DETENER'; % Cambiar el texto del botón
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Voz')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.TuvozhadichoTextAreaLabel = uilabel(app.UIFigure);
            app.TuvozhadichoTextAreaLabel.Text = 'Tu voz ha dicho:';
            app.TuvozhadichoTextArea = uitextarea(app.UIFigure);
            app.GRABARButton = uibutton(app.UIFigure, 'push');
            app.GRABARButton.ButtonPushedFcn = createCallbackFcn(app, @GRABARButtonPushed, true);
            app.GRABARButton.Text = 'GRABAR';
            app.ELIMINARButton = uibutton(app.UIFigure, 'push');
            app.ELIMINARButton.Text = 'ELIMINAR';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'IPN.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'UPIIT.jpg');
            app.BienvenidoaCONVOTunsistemadeconversindevozatextoLabel = uilabel(app.UIFigure);
            app.BienvenidoaCONVOTunsistemadeconversindevozatextoLabel.Text = 'Bienvenido a CONVOT: un sistema de conversión de voz a texto.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CONVET

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end